classdef communication_project1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        VisualizationPanel            matlab.ui.container.Panel
        GenerateButton                matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        ReconstructButton             matlab.ui.control.Button
        SampleButton                  matlab.ui.control.Button
        SamplingOptionsPanel          matlab.ui.container.Panel
        SamplingFreqHzEditField       matlab.ui.control.EditField
        SamplingFreqHzEditFieldLabel  matlab.ui.control.Label
        DemodulationOptionsPanel      matlab.ui.container.Panel
        FilterDropDown                matlab.ui.control.DropDown
        FilterDropDownLabel           matlab.ui.control.Label
        ModulationOptionsPanel        matlab.ui.container.Panel
        ModulationTypeDropDown        matlab.ui.control.DropDown
        ModulationTypeLabel           matlab.ui.control.Label
        ReconstructionPanel           matlab.ui.container.Panel
        CutOffFreqSlider              matlab.ui.control.Slider
        CutOffFreqSliderLabel         matlab.ui.control.Label
        FilterTypeDropDown            matlab.ui.control.DropDown
        FilterTypeDropDownLabel       matlab.ui.control.Label
        CarrierSignalSettingsPanel    matlab.ui.container.Panel
        AmplitudeEditField            matlab.ui.control.EditField
        AmplitudeEditFieldLabel       matlab.ui.control.Label
        FrequencyHzEditField          matlab.ui.control.EditField
        FrequencyHzEditFieldLabel     matlab.ui.control.Label
        MessageSignalSettingsPanel    matlab.ui.container.Panel
        AmplitudeEditField_2          matlab.ui.control.EditField
        AmplitudeEditField_2Label     matlab.ui.control.Label
        FrequencyEditField            matlab.ui.control.EditField
        FrequencyEditFieldLabel       matlab.ui.control.Label
        WaveformDropDown              matlab.ui.control.DropDown
        WaveformDropDownLabel         matlab.ui.control.Label
        SignalandWaveanalysisLabel    matlab.ui.control.Label
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
 
             % 1. Read Inputs
            fm = str2double(app.FrequencyEditField.Value);      % Message Frequency
            Am = str2double(app.AmplitudeEditField_2.Value);    % Message Amplitude
            fc = str2double(app.FrequencyHzEditField.Value);    % Carrier Frequency
            Ac = str2double(app.AmplitudeEditField.Value);      % Carrier Amplitude
            fs = str2double(app.SamplingFreqHzEditField.Value); % Sampling Frequency (Plotting/High-Res)
            waveform = app.WaveformDropDown.Value;
            modType = app.ModulationTypeDropDown.Value;
            
            % Input Validation
            if any(isnan([fm, Am, fc, Ac, fs])) || fs <= 0
                uialert(app.UIFigure, 'Please ensure all frequency and amplitude fields are valid numbers, and sampling frequency is positive.', 'Input Error');
                return;
            end
            
            % Define Time Vector (1 second duration)
            T = 1;
            t = 0 : 1/fs : T - 1/fs; 
            
            % 2. Generate Message Signal m(t)
            switch waveform
                case 'Sine'
                    % m(t) = Am * sin(2*pi*fm*t)
                    m_t = Am * sin(2*pi*fm*t);
                case 'Square'
                    % Simple square wave using sign function
                    m_t = Am * sign(sin(2*pi*fm*t));
                case 'Triangular'
                    % Triangular wave (approximation or use sawtooth with a different duty cycle)
                    m_t = Am * sawtooth(2*pi*fm*t, 0.5); 
                case 'Saw Tooth'
                    % Sawtooth wave
                    m_t = Am * sawtooth(2*pi*fm*t, 1);
                otherwise
                    m_t = zeros(size(t)); % Default to zero signal
            end
            
            % 3. Generate Carrier Signal c(t)
            % c(t) = Ac * cos(2*pi*fc*t)
            c_t = Ac * cos(2*pi*fc*t);
            
            % 4. Calculate Modulated Signal s(t)
            if strcmp(modType, 'AM')
                % AMPLITUDE MODULATION (AM)
                s_t = (Ac + m_t) .* cos(2*pi*fc*t);
            elseif strcmp(modType, 'FM')
                % FREQUENCY MODULATION (FM) 
                kf = 50; % Example frequency deviation constant
                int_m_t = cumtrapz(t, m_t); 
                s_t = Ac * cos(2*pi*fc*t + 2*pi*kf*int_m_t);
            elseif strcmp(modType, 'PM')
                % PHASE MODULATION (PM) 
                kp = pi/2; % Example phase deviation constant
                s_t = Ac * cos(2*pi*fc*t + kp*m_t);
            else
                s_t = zeros(size(t)); % No modulation
                uialert(app.UIFigure, 'Unknown modulation type selected.', 'Error');
            end
            
            % 5. Data Storage using UIFigure.UserData 
            app.UIFigure.UserData.t_full = t;
            app.UIFigure.UserData.s_t_full = s_t;
            app.UIFigure.UserData.modType = modType; 
            app.UIFigure.UserData.m_t = m_t; % Store original message for reference

            app.UIFigure.UserData.t_sampled = []; % Reset sampled data
            app.UIFigure.UserData.s_t_sampled = []; % Reset sampled data
            
            % 6. Plot the Signals
            
            % Plot Message Signal (UIAxes)
            plot(app.UIAxes, t, m_t, 'DisplayName', 'm(t)', 'LineWidth', 1.5);
            title(app.UIAxes, 'Message Signal');
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Amplitude');
            grid(app.UIAxes, 'on');
            
            % Plot Carrier Signal (UIAxes2)
            plot(app.UIAxes2, t, c_t, 'DisplayName', 'c(t)', 'LineWidth', 1.5);
            title(app.UIAxes2, 'Carrier Signal');
            xlabel(app.UIAxes2, 'Time (s)');
            ylabel(app.UIAxes2, 'Amplitude');
            grid(app.UIAxes2, 'on');
            
            % Plot Modulated Signal (UIAxes3)
            plot(app.UIAxes3, t, s_t, 'DisplayName', 's(t)', 'LineWidth', 1.5);
            title(app.UIAxes3, [modType, ' Modulated Signal']);
            xlabel(app.UIAxes3, 'Time (s)');
            ylabel(app.UIAxes3, 'Amplitude');
            grid(app.UIAxes3, 'on');
            
            % Clear Reconstructed Signal (UIAxes4)
            cla(app.UIAxes4);
            title(app.UIAxes4, 'Reconstructed Signal');
            xlabel(app.UIAxes4, 'X');
            ylabel(app.UIAxes4, 'Y');

        end

        % Button pushed function: SampleButton
        function SampleButtonPushed(app, event)
                      
            % 1. Load Data and Check Prerequisites
            if ~isfield(app.UIFigure.UserData, 's_t_full') || isempty(app.UIFigure.UserData.s_t_full)
                uialert(app.UIFigure, 'Please run "Generate" first to create the modulated signal.', 'Error');
                return;
            end
            
            % Load high-resolution signal and time vector
            t_full = app.UIFigure.UserData.t_full;
            s_t_full = app.UIFigure.UserData.s_t_full;

            % 2. Read Desired Sampling Frequency
            fs_sample = str2double(app.SamplingFreqHzEditField.Value); 
            
            % Input Validation
            if isnan(fs_sample) || fs_sample <= 0
                uialert(app.UIFigure, 'Please enter a valid, positive number for Sampling Freq (Hz).', 'Input Error');
                return;
            end
            
            % 3. Calculate Sampling Indices
            T_sample = 1/fs_sample;
            
            % High-resolution time step (from the "Generate" function)
            if length(t_full) > 1
                T_plot = t_full(2) - t_full(1);
            else
                uialert(app.UIFigure, 'Signal is too short for sampling demonstration.', 'Error');
                return;
            end
            
            % Calculate the interval in terms of the high-resolution indices
            index_interval = round(T_sample / T_plot);
            
            if index_interval < 1
                uialert(app.UIFigure, 'Sampling frequency is higher than the plot resolution. Using maximum resolution.', 'Warning');
                index_interval = 1;
            end

            % Select the sampled points (downsampling)
            indices = 1:index_interval:length(t_full);
            t_sampled = t_full(indices);
            s_t_sampled = s_t_full(indices);
            
            % 4. Data Storage in UserData
            app.UIFigure.UserData.t_sampled = t_sampled;
            app.UIFigure.UserData.s_t_sampled = s_t_sampled;
            
            % 5. Plot the Sampled Signal (on UIAxes3 - Modulated Signal plot)
            
            modType = app.UIFigure.UserData.modType;
            
            % Clear the original Modulated Signal plot and hold for overlay
            cla(app.UIAxes3);
            
            % Plot the high-res signal as a background dashed line
            plot(app.UIAxes3, t_full, s_t_full, 'k:', 'LineWidth', 0.5, 'DisplayName', 'Modulated Signal');
            hold(app.UIAxes3, 'on');
            
            % Use stem plot for discrete samples
            stem(app.UIAxes3, t_sampled, s_t_sampled, 'r', 'filled', 'MarkerSize', 5, 'DisplayName', 'Samples');
            
            % Finalize plot
            title(app.UIAxes3, ['Sampled ', modType, ' Signal (Fs=', num2str(fs_sample), ' Hz)']);
            xlabel(app.UIAxes3, 'Time (s)');
            ylabel(app.UIAxes3, 'Amplitude');
            grid(app.UIAxes3, 'on');
            legend(app.UIAxes3, 'off'); 
            hold(app.UIAxes3, 'off');

        end

        % Button pushed function: ReconstructButton
        function ReconstructButtonPushed(app, event)
                        
            % 1. Load Data and Check Prerequisites
            if ~isfield(app.UIFigure.UserData, 's_t_sampled') || isempty(app.UIFigure.UserData.s_t_sampled)
                uialert(app.UIFigure, 'Please run "Generate" and "Sample" first.', 'Error');
                return;
            end

            t_sampled = app.UIFigure.UserData.t_sampled;
            s_t_sampled = app.UIFigure.UserData.s_t_sampled;
            modType = app.UIFigure.UserData.modType;
            
            % Get Fs and Fc from input fields for filter design and PM demod
            fs_filter = str2double(app.SamplingFreqHzEditField.Value); 
            fc = str2double(app.FrequencyHzEditField.Value); % Carrier Frequency for PM reference
            
            % 2. Demodulation Step (Pre-Filtering)
            
            if strcmp(modType, 'AM')
                % AM Demodulation: Envelope Detection (Rectification)
                demodulated_signal = abs(s_t_sampled);
            elseif strcmp(modType, 'FM')
                % FM Demodulation: Differentiate and Rectify (Discriminator Approximation)
                % Differentiate the signal (converts frequency changes to amplitude changes)
                demodulated_signal_raw = diff(s_t_sampled);
                
                % Rectify (Envelope Detect) and scale (removes negative swings)
                demodulated_signal = abs(demodulated_signal_raw);
                
                % Adjust time vector for differentiation (loses one point)
                t_demod = t_sampled(1:end-1);
            elseif strcmp(modType, 'PM')
                % PM Demodulation: Coherent Detection (Multiply by local carrier)
                
                % Generate local carrier signal at the same sampling points
                t_local = t_sampled;
                carrier_local = cos(2*pi*fc*t_local);
                
                % Mix the sampled signal with the local carrier
                demodulated_signal = s_t_sampled .* carrier_local;
            else
                demodulated_signal = s_t_sampled;
            end

            % Adjust time vector for plotting based on differentiation (FM)
            if strcmp(modType, 'FM')
                 t_plot = t_demod;
            else
                 t_plot = t_sampled;
            end


            % 3. Read Filter Settings
            filterType = app.FilterTypeDropDown.Value; % e.g., 'Low Pass'
            cutOffFreq = app.CutOffFreqSlider.Value;   % e.g., 125 Hz
            
            % Input Validation for filter
            if isnan(cutOffFreq) || cutOffFreq <= 0 || cutOffFreq >= fs_filter/2
                % If bad cutoff, use a safe default for reconstruction (e.g., 10 Hz)
                cutOffFreq = 10;
                uialert(app.UIFigure, ['Cut Off Frequency reset to default: ', num2str(cutOffFreq), ' Hz (must be > 0 and < Nyquist freq).'], 'Input Warning');
            end
            
            % 4. Design and Apply Filter (Requires Signal Processing Toolbox)
            
            N = 4; % Filter Order (Butterworth is a good general choice)
            Wn = cutOffFreq / (fs_filter/2); % Normalized cutoff frequency
            
            try
                % Design Butterworth filter based on type
                if startsWith(filterType, 'Low Pass', 'IgnoreCase', true)
                    [b, a] = butter(N, Wn, 'low');
                elseif startsWith(filterType, 'High Pass', 'IgnoreCase', true)
                    [b, a] = butter(N, Wn, 'high');
                % For Band Pass/Stop, we default to low-pass since reconstruction typically uses LPF
                else 
                     [b, a] = butter(N, Wn, 'low');
                end
                
                % Apply the filter to the demodulated signal
                s_reconstructed = filter(b, a, demodulated_signal);
                
            catch ME
                % Fallback if toolbox is missing or input is bad
                uialert(app.UIFigure, 'Filtering failed (Signal Processing Toolbox required). Showing unfiltered demodulated signal.', 'Filter Error');
                s_reconstructed = demodulated_signal; % Show unfiltered signal
            end

            % 5. Plot the Reconstructed Signal (UIAxes4)
            plot(app.UIAxes4, t_plot, s_reconstructed, 'b', 'LineWidth', 2);
            title(app.UIAxes4, ['Reconstructed ', modType, ' (f_c=', num2str(cutOffFreq), ' Hz)']);
            xlabel(app.UIAxes4, 'Time (s)');
            ylabel(app.UIAxes4, 'Amplitude');
            grid(app.UIAxes4, 'on');


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
                        
            % 1. Clear Data Properties stored in UserData
            if isfield(app.UIFigure.UserData, 't_full')
                app.UIFigure.UserData = rmfield(app.UIFigure.UserData, {'t_full', 's_t_full', 't_sampled', 's_t_sampled', 'modType', 'm_t'});
            end
            
            % 2. Clear All Axes and reset titles/labels
            cla(app.UIAxes);
            title(app.UIAxes, 'message signal ');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            
            cla(app.UIAxes2);
            title(app.UIAxes2, 'Carrier Signal');
            xlabel(app.UIAxes2, 'X');
            ylabel(app.UIAxes2, 'Y');
            
            cla(app.UIAxes3);
            title(app.UIAxes3, 'Modulated Signal');
            xlabel(app.UIAxes3, 'X');
            ylabel(app.UIAxes3, 'Y');
            
            cla(app.UIAxes4);
            title(app.UIAxes4, 'Reconstructed Signal');
            xlabel(app.UIAxes4, 'X');
            ylabel(app.UIAxes4, 'Y');
            
            % 3. Reset Input Fields (User inputs)
            app.FrequencyEditField.Value = '';
            app.AmplitudeEditField_2.Value = '';
            app.FrequencyHzEditField.Value = '';
            app.AmplitudeEditField.Value = '';
            app.SamplingFreqHzEditField.Value = '';
            
            % 4. Reset Dropdowns/Sliders
            app.WaveformDropDown.Value = 'Sine';
            app.ModulationTypeDropDown.Value = 'AM';
            app.FilterDropDown.Value = 'Low Pass';
            app.FilterTypeDropDown.Value = 'Low Pass';
            app.CutOffFreqSlider.Value = 0; 
 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'message signal ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Carrier Signal')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Modulated Signal')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Reconstructed Signal')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.SignalandWaveanalysisLabel = uilabel(app.UIFigure);
            app.SignalandWaveanalysisLabel.Text = 'Signal and Wave analysis';
            app.MessageSignalSettingsPanel = uipanel(app.UIFigure);
            app.MessageSignalSettingsPanel.Title = 'Message Signal Settings';
            app.WaveformDropDownLabel = uilabel(app.MessageSignalSettingsPanel);
            app.WaveformDropDownLabel.Text = 'Waveform';
            app.WaveformDropDown = uidropdown(app.MessageSignalSettingsPanel);
            app.WaveformDropDown.Items = {'Sine', 'Saw Tooth', 'Square', 'Triangular'};
            app.WaveformDropDown.Value = 'Sine';
            app.FrequencyEditFieldLabel = uilabel(app.MessageSignalSettingsPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.MessageSignalSettingsPanel, 'text');
            app.AmplitudeEditField_2Label = uilabel(app.MessageSignalSettingsPanel);
            app.AmplitudeEditField_2Label.Text = 'Amplitude';
            app.AmplitudeEditField_2 = uieditfield(app.MessageSignalSettingsPanel, 'text');
            app.CarrierSignalSettingsPanel = uipanel(app.UIFigure);
            app.CarrierSignalSettingsPanel.Title = 'Carrier Signal Settings';
            app.FrequencyHzEditFieldLabel = uilabel(app.CarrierSignalSettingsPanel);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency(Hz)';
            app.FrequencyHzEditField = uieditfield(app.CarrierSignalSettingsPanel, 'text');
            app.AmplitudeEditFieldLabel = uilabel(app.CarrierSignalSettingsPanel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.CarrierSignalSettingsPanel, 'text');
            app.ReconstructionPanel = uipanel(app.UIFigure);
            app.ReconstructionPanel.Title = 'Reconstruction';
            app.FilterTypeDropDownLabel = uilabel(app.ReconstructionPanel);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.ReconstructionPanel);
            app.FilterTypeDropDown.Items = {'Low Pass', 'High Pass', 'Band Pass ', 'Band Stop'};
            app.FilterTypeDropDown.Value = 'Low Pass';
            app.CutOffFreqSliderLabel = uilabel(app.ReconstructionPanel);
            app.CutOffFreqSliderLabel.Text = 'Cut Off Freq';
            app.CutOffFreqSlider = uislider(app.ReconstructionPanel);
            app.CutOffFreqSlider.Limits = [0 500];
            app.ModulationOptionsPanel = uipanel(app.UIFigure);
            app.ModulationOptionsPanel.Title = 'Modulation Options';
            app.ModulationTypeLabel = uilabel(app.ModulationOptionsPanel);
            app.ModulationTypeLabel.Text = {'Modulation'; 'Type'};
            app.ModulationTypeDropDown = uidropdown(app.ModulationOptionsPanel);
            app.ModulationTypeDropDown.Items = {'AM', 'PM', 'FM'};
            app.ModulationTypeDropDown.Value = 'AM';
            app.DemodulationOptionsPanel = uipanel(app.UIFigure);
            app.DemodulationOptionsPanel.Title = 'Demodulation Options';
            app.FilterDropDownLabel = uilabel(app.DemodulationOptionsPanel);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.DemodulationOptionsPanel);
            app.FilterDropDown.Items = {'Low Pass', 'High Pass', ''};
            app.FilterDropDown.Value = 'Low Pass';
            app.SamplingOptionsPanel = uipanel(app.UIFigure);
            app.SamplingOptionsPanel.Title = 'Sampling Options';
            app.SamplingFreqHzEditFieldLabel = uilabel(app.SamplingOptionsPanel);
            app.SamplingFreqHzEditFieldLabel.Text = 'Sampling Freq (Hz)';
            app.SamplingFreqHzEditField = uieditfield(app.SamplingOptionsPanel, 'text');
            app.VisualizationPanel = uipanel(app.UIFigure);
            app.VisualizationPanel.Title = 'Visualization';
            app.SampleButton = uibutton(app.VisualizationPanel, 'push');
            app.SampleButton.ButtonPushedFcn = createCallbackFcn(app, @SampleButtonPushed, true);
            app.SampleButton.Text = 'Sample';
            app.ReconstructButton = uibutton(app.VisualizationPanel, 'push');
            app.ReconstructButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructButtonPushed, true);
            app.ReconstructButton.Text = 'Reconstruct';
            app.ResetButton = uibutton(app.VisualizationPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GenerateButton = uibutton(app.VisualizationPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = communication_project1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end